classdef InterfazpruebaG4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlcoholLabel                  matlab.ui.control.Label
        GeneroLabel                   matlab.ui.control.Label
        ECVLabel                      matlab.ui.control.Label
        ActividadFisicaLabel          matlab.ui.control.Label
        FumaLabel                     matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        puntos56                      matlab.ui.control.ListBox
        QuedeseagraficarListBoxLabel  matlab.ui.control.Label
        icmestatico                   matlab.ui.control.Label
        icmdinamico                   matlab.ui.control.Label
        calcularicm                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PacientesconECVTab            matlab.ui.container.Tab
        calcularconECV                matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        DesviacionestandarLabel_2     matlab.ui.control.Label
        MediaLabel_2                  matlab.ui.control.Label
        selecconECV                   matlab.ui.control.ListBox
        ListBoxLabel_2                matlab.ui.control.Label
        PacientessinECVTab            matlab.ui.container.Tab
        CalcularButton                matlab.ui.control.Button
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        DesviacionestandarLabel       matlab.ui.control.Label
        MediaLabel                    matlab.ui.control.Label
        DequevariabledeseaconocerlamediayladesviacionestandarListBox  matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        IDparticipante                matlab.ui.control.NumericEditField
        IDpersonaEditFieldLabel       matlab.ui.control.Label
        Graficarboton                 matlab.ui.control.Button
        grafica1                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HeartData %tabla con datos de enfermedades cardiacas
        
    end
    
    methods (Access = private)
        
        function  preprocesamiento(app)
            app.HeartData.height = app.HeartData.height / 100;
            app.HeartData.age = app.HeartData.age /365;


fuma =   app.HeartData.smoke; %%parte para covertir 1,0 en "fumador"
texfum = ["No fuma"; "Fuma"];
reemplazofum = texfum (fuma + 1); 
app.HeartData.smoke = reemplazofum;


alcohol= app.HeartData.alco; %%parte para covertir 1,0 en "alcohol"
texalcohol = ["No consume alcohol"; "Consume alcohol"];
reemplazoalcohol = texalcohol (alcohol + 1);
app.HeartData.alco = reemplazoalcohol;


actividad = app.HeartData.active;  %%parte para covertir 1,0 en "actividad"
texactividad = ["No hace actividad fisica"; "Hace actividad fisica"];
reemplazoactividad = texactividad (actividad + 1);
app.HeartData.active = reemplazoactividad;


cardio = app.HeartData.cardio; %%parte para covertir 1,0 en "ECV"
texcardio = ["No tiene ECV"; "Tiene ECV"];
reemplazocardio = texcardio (cardio + 1);
app.HeartData.cardio = reemplazocardio;


genero = app.HeartData.gender; %%genero
texgenero = ["Hombre"; "Mujer"];
reemplazogenero = texgenero (genero);
app.HeartData.gender = reemplazogenero;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load("workspacesg4.mat","TablaConTodosLosDatos");
app.HeartData = TablaConTodosLosDatos;
preprocesamiento(app);
        end

        % Button pushed function: Graficarboton
        function GraficarbotonButtonPushed(app, event)


        end

        % Button pushed function: calcularicm
        function calcularicmButtonPushed(app, event)
            ID = app.IDparticipante.Value;
peso = app.HeartData.weight(ID);
altura = app.HeartData.height(ID);
imc = peso/altura^2;
app.icmdinamico.Text= num2str(imc, "%.2f");

fuma = app.HeartData.smoke(ID); %%prueba fumador
app.Label_2.Text = num2str(fuma);

alcohol = app.HeartData.alco(ID); %%prueba alcohol
app.Label_3.Text = num2str(alcohol); 

actividad = app.HeartData.active (ID); %%prueba actividad fisica
app.Label_4.Text = num2str(actividad);

cardio = app.HeartData.cardio (ID);
app.Label_5.Text = num2str(cardio);

genero = app.HeartData.gender (ID);
app.Label_6.Text = num2str(genero);


        end

        % Value changed function: puntos56
        function puntos56ValueChanged(app, event)
            
        end

        % Button pushed function: calcularconECV
        function calcularconECVPushed(app, event)
    
            switch app.selecconECV
                case "Índice de masa corporal"
                    
            end

        end

        % Value changed function: selecconECV
        function selecconECVValueChanged(app, event)
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica1 = uiaxes(app.UIFigure);
            title(app.grafica1, 'Peso vs Altura')
            xlabel(app.grafica1, 'Peso')
            ylabel(app.grafica1, 'Altura')
            zlabel(app.grafica1, 'Z')
            app.Graficarboton = uibutton(app.UIFigure, 'push');
            app.Graficarboton.ButtonPushedFcn = createCallbackFcn(app, @GraficarbotonButtonPushed, true);
            app.Graficarboton.Text = 'Graficar';
            app.IDpersonaEditFieldLabel = uilabel(app.UIFigure);
            app.IDpersonaEditFieldLabel.Text = 'ID persona';
            app.IDparticipante = uieditfield(app.UIFigure, 'numeric');
            app.IDparticipante.Limits = [1 70000];
            app.IDparticipante.ValueDisplayFormat = '%.0f';
            app.IDparticipante.Placeholder = '1';
            app.IDparticipante.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PacientesconECVTab = uitab(app.TabGroup);
            app.PacientesconECVTab.Title = 'Pacientes con ECV';
            app.ListBoxLabel_2 = uilabel(app.PacientesconECVTab);
            app.ListBoxLabel_2.Text = {'¿De que variable desea'; 'conocer la media y la'; 'desviacion estandar?'};
            app.selecconECV = uilistbox(app.PacientesconECVTab);
            app.selecconECV.Items = {'Índice de masa corporal', 'Presión arterial media', 'Colesterol'};
            app.selecconECV.ValueChangedFcn = createCallbackFcn(app, @selecconECVValueChanged, true);
            app.selecconECV.Value = 'Índice de masa corporal';
            app.MediaLabel_2 = uilabel(app.PacientesconECVTab);
            app.MediaLabel_2.Text = 'Media:';
            app.DesviacionestandarLabel_2 = uilabel(app.PacientesconECVTab);
            app.DesviacionestandarLabel_2.Text = 'Desviacion estandar:';
            app.Label_7 = uilabel(app.PacientesconECVTab);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.PacientesconECVTab);
            app.Label_8.Text = '';
            app.calcularconECV = uibutton(app.PacientesconECVTab, 'push');
            app.calcularconECV.ButtonPushedFcn = createCallbackFcn(app, @calcularconECVPushed, true);
            app.calcularconECV.Text = 'Calcular';
            app.PacientessinECVTab = uitab(app.TabGroup);
            app.PacientessinECVTab.Title = 'Pacientes sin ECV';
            app.ListBoxLabel = uilabel(app.PacientessinECVTab);
            app.ListBoxLabel.Text = {'¿De que variable desea'; 'conocer la media y la'; 'desviacion estandar?'};
            app.DequevariabledeseaconocerlamediayladesviacionestandarListBox = uilistbox(app.PacientessinECVTab);
            app.DequevariabledeseaconocerlamediayladesviacionestandarListBox.Items = {'Índice de masa corporal', 'Presión arterial media', 'Colesterol'};
            app.DequevariabledeseaconocerlamediayladesviacionestandarListBox.Value = 'Índice de masa corporal';
            app.MediaLabel = uilabel(app.PacientessinECVTab);
            app.MediaLabel.Text = 'Media:';
            app.DesviacionestandarLabel = uilabel(app.PacientessinECVTab);
            app.DesviacionestandarLabel.Text = 'Desviacion estandar:';
            app.Label = uilabel(app.PacientessinECVTab);
            app.Label2 = uilabel(app.PacientessinECVTab);
            app.Label2.Text = 'Label2';
            app.CalcularButton = uibutton(app.PacientessinECVTab, 'push');
            app.CalcularButton.Text = 'Calcular';
            app.calcularicm = uibutton(app.UIFigure, 'push');
            app.calcularicm.ButtonPushedFcn = createCallbackFcn(app, @calcularicmButtonPushed, true);
            app.calcularicm.Text = 'Informacion';
            app.icmdinamico = uilabel(app.UIFigure);
            app.icmdinamico.Text = '';
            app.icmestatico = uilabel(app.UIFigure);
            app.icmestatico.Text = 'El ICM es:';
            app.QuedeseagraficarListBoxLabel = uilabel(app.UIFigure);
            app.QuedeseagraficarListBoxLabel.Text = '¿Que desea graficar?';
            app.puntos56 = uilistbox(app.UIFigure);
            app.puntos56.Items = {'Punto 5', 'Punto 6'};
            app.puntos56.ValueChangedFcn = createCallbackFcn(app, @puntos56ValueChanged, true);
            app.puntos56.Value = 'Punto 5';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.FumaLabel = uilabel(app.UIFigure);
            app.FumaLabel.Text = 'Fuma:';
            app.ActividadFisicaLabel = uilabel(app.UIFigure);
            app.ActividadFisicaLabel.Text = 'Actividad Fisica:';
            app.ECVLabel = uilabel(app.UIFigure);
            app.ECVLabel.Text = 'ECV:';
            app.GeneroLabel = uilabel(app.UIFigure);
            app.GeneroLabel.Text = 'Genero:';
            app.AlcoholLabel = uilabel(app.UIFigure);
            app.AlcoholLabel.Text = 'Alcohol:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazpruebaG4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end